classdef InterfaceGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MNumricosparaEDOPVIUIFigure   matlab.ui.Figure
        FicheiroMenu                  matlab.ui.container.Menu
        AbrirrelatrioMenu             matlab.ui.container.Menu
        SairMenu                      matlab.ui.container.Menu
        AutoresMenu                   matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ExportarparaexcelButton       matlab.ui.control.Button
        ExportargraficoparaimagemButton  matlab.ui.control.Button
        bAtualizar_2                  matlab.ui.control.Button
        bAtualizar                    matlab.ui.control.Button
        bgMetodos                     matlab.ui.container.ButtonGroup
        rbTodos                       matlab.ui.control.RadioButton
        rbOutro                       matlab.ui.control.RadioButton
        rbODE45                       matlab.ui.control.RadioButton
        rbRK4                         matlab.ui.control.RadioButton
        rbRK2                         matlab.ui.control.RadioButton
        rbEulerM                      matlab.ui.control.RadioButton
        rbEuler                       matlab.ui.control.RadioButton
        DadosdoPVIPanel               matlab.ui.container.Panel
        eY0                           matlab.ui.control.NumericEditField
        aEditFieldLabel_4             matlab.ui.control.Label
        eN                            matlab.ui.control.NumericEditField
        aEditFieldLabel_3             matlab.ui.control.Label
        eB                            matlab.ui.control.NumericEditField
        aEditFieldLabel_2             matlab.ui.control.Label
        eA                            matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        eF                            matlab.ui.control.EditField
        ftyEditFieldLabel             matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TiraroumostrarnograficoPanel  matlab.ui.container.Panel
        ODE23CheckBox                 matlab.ui.control.CheckBox
        ODE45CheckBox                 matlab.ui.control.CheckBox
        RK4CheckBox                   matlab.ui.control.CheckBox
        RK2CheckBox                   matlab.ui.control.CheckBox
        EulerMelhoradoCheckBox        matlab.ui.control.CheckBox
        EulerCheckBox                 matlab.ui.control.CheckBox
        GrelhaSwitch                  matlab.ui.control.Switch
        GrelhaSwitchLabel             matlab.ui.control.Label
        LegendaSwitch                 matlab.ui.control.Switch
        LegendaSwitchLabel            matlab.ui.control.Label
        MtodosNumricosparaEDOPVIAM2Label  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            app.LegendaSwitch.Value = 'on';
            app.GrelhaSwitch.Value = 'on';
            cla(app.UIAxes)
            syms y(t)
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;

            escolhabgMetodos = app.bgMetodos.SelectedObject;
            rbMetodoSelecionado = find([app.rbEuler,...
                app.rbEulerM,...
                app.rbRK2,...
                app.rbRK4,...
                app.rbODE45,...
                app.rbOutro,...
                app.rbTodos]==escolhabgMetodos);
            %erroN = MException(Matlab:nAtualizar) %codigo erro a ser implementado
            erroFuncao = MException("Matlab:bAtualizar:badF",...
                "Introduza uma função em t e y" );

            erroB = MException("Matlab:bAtualizar:badB",...
                "O valor de 'b' tem de ser superior ao de 'a'" );

            try
                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";

                catch ME
                    app.eF.BackgroundColor = "r";
                    throw(erroFuncao)
                end

                if ~(isreal(b) && b > a)
                    app.eB.BackgroundColor = "r";
                     app.eA.BackgroundColor = "r";
                    throw(erroB)
                else
                    app.eB.BackgroundColor = "w";
                    app.eA.BackgroundColor = "w";

                end

                
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));

                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);
                [~,yRK4] = NRK4(f,a,b,n,y0);
                [t,yNEuler_melhorado]  = NEuler_melhorado(f,a,b,n,y0);
                [~,yODE45] = ODE45(f,a,b,n,y0);
                [~,yNODE23] = NODE23(f,a,b,n,y0);

                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroEuler_melhorado = abs(yExata-yNEuler_melhorado);
                erroODE45 = abs(yExata-(yODE45.'));
                erroNODE23 = abs(yExata-yNODE23);
                plot(app.UIAxes,t,yExata,"-dc")
                hold(app.UIAxes,"on")

                switch rbMetodoSelecionado
                    case 1
                        plot(app.UIAxes,t,yEuler,"-.<m");
                        matrizResultados = [t.', yExata.', yEuler.',erroEuler.'];
                        legendaGrafico = ["Exata","Euler"];
                        NomeColunasTabela = ["t","Exata","Euler","ErroEuler"];
                    case 2
                        plot(app.UIAxes,t,yNEuler_melhorado,"-kx");
                        matrizResultados = [t.', yExata.', yNEuler_melhorado.',erroEuler_melhorado.'];
                        legendaGrafico = ["Exata","Euler Melhorado"];
                        NomeColunasTabela = ["t","Exata","EulerMelhorado","erroEulerMelhorado"];
                    case 3
                        plot(app.UIAxes,t,yRK2,"-+r")
                        matrizResultados = [t.', yExata.', yRK2.',erroRK2.'];
                        legendaGrafico = ["Exata","RK2"];
                        NomeColunasTabela = ["t","Exata","RK2","ErroRK2"];
                    case 4
                        plot(app.UIAxes,t,yRK4,"-..b")
                        matrizResultados = [t.', yExata.', yRK4.',erroRK4.'];
                        legendaGrafico = ["Exata","RK4"];
                        NomeColunasTabela = ["t","Exata","RK4","ErroRK4"];
                    case 5
                        plot(app.UIAxes,t,yODE45,"--yh")
                        matrizResultados = [t.',yExata.',yODE45,erroODE45.'];
                        legendaGrafico = ["Exata","ODE45"];
                        NomeColunasTabela = ["t","Exata","ODE45","ErroODE45"];
                    case 6
                        plot(app.UIAxes,t,yNODE23,"-vg")
                        matrizResultados = [t.',yExata.',yNODE23.',erroNODE23.'];
                        legendaGrafico = ["Exata","NODE23"];
                        NomeColunasTabela = ["t","Exata","NODE23","ErroNODE23"];
                    case 7
                        plot(app.UIAxes,t,yEuler,"-.<m");
                        plot(app.UIAxes,t,yNEuler_melhorado,"-kx");
                        plot(app.UIAxes,t,yRK2,"-+r");
                        plot(app.UIAxes,t,yRK4,"-..b");
                        plot(app.UIAxes,t,yODE45,"--yh");
                        plot(app.UIAxes,t,yNODE23,"-vg");
                        matrizResultados = [t.',yExata.',yEuler.',yNEuler_melhorado.',yRK2.',yRK4.',yODE45,yNODE23.'...
                            erroEuler.',erroEuler_melhorado.',erroRK2.',erroRK4.',erroODE45.',erroNODE23.'];
                        legendaGrafico = ["Exata","Euler","Euler Melhorado","RK2","RK4","ODE45","NODE23"];
                        NomeColunasTabela = ["t","Exata","Euler","Euler Melhorado","RK2","RK4","ODE45","NODE23"...
                            "ErroEuler","ErroEulerMelhorado","ErroRK2","ErroRK4","ErroODE45","erroNODE23"];

                end
                 

                grid(app.UIAxes,"on")
                legend(app.UIAxes,legendaGrafico)

                app.UITable.Data = array2table(matrizResultados);
                app.UITable.ColumnName = NomeColunasTabela;
                
             
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.MNumricosparaEDOPVIUIFigure)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
           autoress
        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            %botao reset
            % Mostra uma caixa de diálogo com uma mensagem e botões de opção

            choice = questdlg('Tem certeza que quer dar reset?', ...
                'Confirmação', ...
                'Sim','Não','Não');
            % Verifica qual botão foi pressionado
            switch choice
                case 'Sim'

                    app.eF.Value = 'y+exp(3*t)';%apagar a funcao (string)
                    app.eN.Value = 2; %valor numerico
                    app.eA.Value = 0;
                    app.eY0.Value = 2;
                    app.eB.Value = 1.5;
                    app.bgMetodos.SelectedObject = app.rbEuler; %botao euler a ser inicializado
                    bAtualizarButtonPushed(app, event); %chamar a funcao inicializada

                case 'Não'
                    % Não faz o reset
            end

        end

        % Menu selected function: AbrirrelatrioMenu
        function AbrirrelatrioMenuSelected(app, event)
            open('Tiago_Jose_Gaspar_Oliveira-2022137225_Pedro_Manuel_Martins-2021118351_Pedro_da_Costa_Sherring-2020126540.pdf');
        end

        % Button pushed function: ExportargraficoparaimagemButton
        function ExportargraficoparaimagemButtonPushed(app, event)
            % Receber o nome do ficheiro
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);

            % Tira uma print ao grafico
            exportgraphics(app.UIAxes, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Button pushed function: ExportarparaexcelButton
        function ExportarparaexcelButtonPushed(app, event)

            % Obter os dados da tabela que deseja exportar
            dados = app.UITable.Data;
            colunas = app.UITable.ColumnName;

            % Verificar se há tabelas aninhadas e convertê-las em células
            dados = convertNestedTablesToCells(dados);

            % Criar uma tabela a partir dos dados e nomes das colunas
            tabela = array2table(dados, 'VariableNames', colunas);

            % Especificar o nome do arquivo Excel de destino
            nomeFicheiro = 'dados.xlsx';

            % Salvar a tabela no arquivo Excel
            writetable(tabela, nomeFicheiro);

            % Exibir uma mensagem de confirmação
            msgbox('Tabela exportada com sucesso para o Excel.', 'Exportação Concluída');

            % Função para converter tabelas aninhadas em células
            function data = convertNestedTablesToCells(data)
                if istable(data)
                    data = table2cell(data);
                elseif iscell(data)
                    for i = 1:numel(data)
                        data{i} = convertNestedTablesToCells(data{i});
                    end
                end
            end
            winopen(nomeFicheiro);


        end

        % Value changed function: LegendaSwitch
        function LegendaSwitchValueChanged(app, event)
    value = app.LegendaSwitch.Value;
        if strcmp(value, 'on')
            legend(app.UIAxes);
             else
                legend(app.UIAxes, 'off');
        end
            
        end

        % Value changed function: GrelhaSwitch
        function GrelhaSwitchValueChanged(app, event)
    value = app.GrelhaSwitch.Value;
    if strcmp(value, 'on')
        grid(app.UIAxes, 'on');
    else
        grid(app.UIAxes, 'off');
    end
        end

        % Value changed function: EulerCheckBox
        function EulerCheckBoxValueChanged(app, event)
    if app.EulerCheckBox.Value

      
        h = findobj(app.UIAxes, 'Type', 'Line', 'Color', [1 0 1]); 
        delete(h);

    else
       
        syms y(t);
        strF = app.eF.Value;
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;
        n = app.eN.Value;
        y0 = app.eY0.Value;
       
  
        [t, yEuler] = NEuler(f, a, b, n, y0);
      

        plot(app.UIAxes, t, yEuler, '-.<m');
        
     
    end
            
        end

        % Value changed function: EulerMelhoradoCheckBox
        function EulerMelhoradoCheckBoxValueChanged(app, event)
    if app.EulerMelhoradoCheckBox.Value
      
        h = findobj(app.UIAxes, 'Type', 'Line', 'Color', [0 0 0]); 
        delete(h);
    else
        
        syms y(t);
        strF = app.eF.Value;
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;
        n = app.eN.Value;
        y0 = app.eY0.Value;
        
     
        [t, yNEuler_melhorado] = NEuler_melhorado(f, a, b, n, y0);
        
     
        plot(app.UIAxes, t, yNEuler_melhorado, '-kx');

    end           
        end

        % Value changed function: RK2CheckBox
        function RK2CheckBoxValueChanged(app, event)
if app.RK2CheckBox.Value
 
    h = findobj(app.UIAxes, 'Type', 'Line', 'Color', [1 0 0]);
 
    delete(h);
else
  
    syms y(t);
    strF = app.eF.Value;
    f = @(t,y) eval(vectorize(strF));
    a = app.eA.Value;
    b = app.eB.Value;
    n = app.eN.Value;
    y0 = app.eY0.Value;

    [t, yRK2] = NRK2(f, a, b, n, y0);

    plot(app.UIAxes, t, yRK2, '-+r');
  
    
end  
        end

        % Value changed function: RK4CheckBox
        function RK4CheckBoxValueChanged(app, event)
     if app.RK4CheckBox.Value
      
        h = findobj(app.UIAxes, 'Type', 'Line', '-and', 'Color', [0 0 1]);
      
        delete(h);
    else
        
        syms y(t);
        strF = app.eF.Value;
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;
        n = app.eN.Value;
        y0 = app.eY0.Value;
    
    
        [t, yRK4] = NRK4(f, a, b, n, y0);
    
        % Plota a linha RK4 com a cor azul
        plot(app.UIAxes, t, yRK4, '-..b');
     end
            
        end

        % Value changed function: ODE45CheckBox
        function ODE45CheckBoxValueChanged(app, event)
         if app.ODE45CheckBox.Value
    
      h = findobj(app.UIAxes, 'Type', 'Line', '-and', 'Color', [1 1 0]);
    
       delete(h);
        else
   
        syms y(t);
        strF = app.eF.Value;
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;
        n = app.eN.Value;
        y0 = app.eY0.Value;

        [t, yODE45] = ODE45(f, a, b, n, y0);
    
        plot(app.UIAxes, t, yODE45, '--yh');
        
        end
            
        end

        % Value changed function: ODE23CheckBox
        function ODE23CheckBoxValueChanged(app, event)
    if app.ODE23CheckBox.Value

    h = findobj(app.UIAxes, 'Type', 'Line', '-and', 'Color', [0 1 0]);
  
    delete(h);
    else

    syms y(t);
    strF = app.eF.Value;
    f = @(t,y) eval(vectorize(strF));
    a = app.eA.Value;
    b = app.eB.Value;
    n = app.eN.Value;
    y0 = app.eY0.Value;

   
    [t, yNODE23] = NODE23(f, a, b, n, y0);

    plot(app.UIAxes, t, yNODE23, '-vg');
    
    end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MNumricosparaEDOPVIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {423, 423};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MNumricosparaEDOPVIUIFigure = uifigure('Visible', 'off');
            app.MNumricosparaEDOPVIUIFigure.Name = 'MNuméricos para EDO/PVI';
            app.MNumricosparaEDOPVIUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.MNumricosparaEDOPVIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FicheiroMenu = uimenu(app.MNumricosparaEDOPVIUIFigure);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.AbrirrelatrioMenu = uimenu(app.FicheiroMenu);
            app.AbrirrelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirrelatrioMenuSelected, true);
            app.AbrirrelatrioMenu.Text = 'Abrir relatório';
            app.SairMenu = uimenu(app.FicheiroMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.AutoresMenu = uimenu(app.MNumricosparaEDOPVIUIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MNumricosparaEDOPVIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'n';
            app.eN = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eN.Value = 2;
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos Numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbOutro = uiradiobutton(app.bgMetodos);
            app.rbOutro.Text = 'ODE23';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bAtualizar_2 = uibutton(app.LeftPanel, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Reset';
            app.ExportargraficoparaimagemButton = uibutton(app.LeftPanel, 'push');
            app.ExportargraficoparaimagemButton.ButtonPushedFcn = createCallbackFcn(app, @ExportargraficoparaimagemButtonPushed, true);
            app.ExportargraficoparaimagemButton.Text = 'Exportar grafico para imagem ';
            app.ExportarparaexcelButton = uibutton(app.LeftPanel, 'push');
            app.ExportarparaexcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarparaexcelButtonPushed, true);
            app.ExportarparaexcelButton.Text = 'Exportar para excel';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MtodosNumricosparaEDOPVIAM2Label = uilabel(app.RightPanel);
            app.MtodosNumricosparaEDOPVIAM2Label.Text = 'Métodos Numéricos para EDO/PVI - AM2';
            app.LegendaSwitchLabel = uilabel(app.RightPanel);
            app.LegendaSwitchLabel.Text = 'Legenda';
            app.LegendaSwitch = uiswitch(app.RightPanel, 'slider');
            app.LegendaSwitch.Items = {'off', 'on'};
            app.LegendaSwitch.ValueChangedFcn = createCallbackFcn(app, @LegendaSwitchValueChanged, true);
            app.LegendaSwitch.Value = 'off';
            app.GrelhaSwitchLabel = uilabel(app.RightPanel);
            app.GrelhaSwitchLabel.Text = 'Grelha';
            app.GrelhaSwitch = uiswitch(app.RightPanel, 'slider');
            app.GrelhaSwitch.Items = {'off', 'on'};
            app.GrelhaSwitch.ValueChangedFcn = createCallbackFcn(app, @GrelhaSwitchValueChanged, true);
            app.GrelhaSwitch.Value = 'off';
            app.TiraroumostrarnograficoPanel = uipanel(app.RightPanel);
            app.TiraroumostrarnograficoPanel.Title = 'Tirar ou mostrar no grafico';
            app.EulerCheckBox = uicheckbox(app.TiraroumostrarnograficoPanel);
            app.EulerCheckBox.ValueChangedFcn = createCallbackFcn(app, @EulerCheckBoxValueChanged, true);
            app.EulerCheckBox.Text = 'Euler';
            app.EulerMelhoradoCheckBox = uicheckbox(app.TiraroumostrarnograficoPanel);
            app.EulerMelhoradoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EulerMelhoradoCheckBoxValueChanged, true);
            app.EulerMelhoradoCheckBox.Text = 'Euler Melhorado';
            app.RK2CheckBox = uicheckbox(app.TiraroumostrarnograficoPanel);
            app.RK2CheckBox.ValueChangedFcn = createCallbackFcn(app, @RK2CheckBoxValueChanged, true);
            app.RK2CheckBox.Text = 'RK2';
            app.RK4CheckBox = uicheckbox(app.TiraroumostrarnograficoPanel);
            app.RK4CheckBox.ValueChangedFcn = createCallbackFcn(app, @RK4CheckBoxValueChanged, true);
            app.RK4CheckBox.Text = 'RK4';
            app.ODE45CheckBox = uicheckbox(app.TiraroumostrarnograficoPanel);
            app.ODE45CheckBox.ValueChangedFcn = createCallbackFcn(app, @ODE45CheckBoxValueChanged, true);
            app.ODE45CheckBox.Text = 'ODE45';
            app.ODE23CheckBox = uicheckbox(app.TiraroumostrarnograficoPanel);
            app.ODE23CheckBox.ValueChangedFcn = createCallbackFcn(app, @ODE23CheckBoxValueChanged, true);
            app.ODE23CheckBox.Text = 'ODE23';
            % Show the figure after all components are created
            app.MNumricosparaEDOPVIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MNumricosparaEDOPVIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MNumricosparaEDOPVIUIFigure)
        end
    end
end