classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MquinaCDIUIFigure               matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        AbrirRelatorioMenu              matlab.ui.container.Menu
        AutoresMenu                     matlab.ui.container.Menu
        SairMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        tgFrVr                          matlab.ui.container.Tab
        DerivadaLabel_2                 matlab.ui.control.Label
        ExportarGrficoparaImagemButton  matlab.ui.control.Button
        ExportarTabelaparaexcelButton   matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SelecioneaDerivadaDropDown      matlab.ui.control.DropDown
        SelecioneaDerivadaDropDownLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ExecutarButton_2                matlab.ui.control.Button
        DadosdafunoPanel                matlab.ui.container.Panel
        eB                              matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        eH                              matlab.ui.control.NumericEditField
        hLabel                          matlab.ui.control.Label
        eA                              matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        fxLabel                         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        tgFrVr_2                        matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        harmonica_2                     matlab.ui.control.Label
        harmonicaLabel                  matlab.ui.control.Label
        IntegralduploLabel              matlab.ui.control.Label
        DerivadaLabel_3                 matlab.ui.control.Label
        ExportarGrafico2paraimagemButton  matlab.ui.control.Button
        ExportarGrafico1paraimagemButton  matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ExecutarButton_3                matlab.ui.control.Button
        DadosdafunoPanel_2              matlab.ui.container.Panel
        eH_2                            matlab.ui.control.NumericEditField
        h1Label                         matlab.ui.control.Label
        eH_3                            matlab.ui.control.NumericEditField
        h2Label                         matlab.ui.control.Label
        eB_2                            matlab.ui.control.NumericEditField
        bLabel_2                        matlab.ui.control.Label
        eD_2                            matlab.ui.control.NumericEditField
        dLabel                          matlab.ui.control.Label
        eC_2                            matlab.ui.control.NumericEditField
        cLabel                          matlab.ui.control.Label
        eA_2                            matlab.ui.control.NumericEditField
        aLabel_2                        matlab.ui.control.Label
        eF_2                            matlab.ui.control.EditField
        xyLabel                         matlab.ui.control.Label
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        IntegraonmericaTab              matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        IntegralLabel                   matlab.ui.control.Label
        eN                              matlab.ui.control.NumericEditField
        nLabel                          matlab.ui.control.Label
        eF_3                            matlab.ui.control.EditField
        ExpresoLabel                    matlab.ui.control.Label
        eA_3                            matlab.ui.control.NumericEditField
        aEditField_2Label               matlab.ui.control.Label
        eB_3                            matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        UIIntegral                      matlab.ui.control.UIAxes
        bgMetodos                       matlab.ui.container.ButtonGroup
        ResultadodosMtodosdeIntegraaoLabel  matlab.ui.control.Label
        ResetButton_3                   matlab.ui.control.Button
        ExecutarButton                  matlab.ui.control.Button
        TodosButton                     matlab.ui.control.RadioButton
        RegraSimpsonButton              matlab.ui.control.RadioButton
        RegraTrapziosButton             matlab.ui.control.RadioButton
        UIRegras                        matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function YN = isHarmonica(~,f)
            syms z(x,y)
            if (diff(f,x,2)+diff(f,y,2)==0)
                YN = 1;
            else
                YN = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ns)
            ExecutarButton_2Pushed(app, [])
            ExecutarButton_3Pushed(app, [])

        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)

            syms y(x)
            strF = app.eF.Value;

            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; h = app.eH.Value; b = app.eB.Value;


            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                app.eB.BackgroundColor = 'r';
                app.eB.FontColor = "black";
                app.eA.BackgroundColor = 'r';
                app.eA.FontColor = "black";
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza b > a');
                throw(erroFuncao)
            else

                app.eB.BackgroundColor = "w";
                app.eB.FontColor = [0,0,0];
                app.eA.BackgroundColor = "w";
                app.eA.FontColor = [0,0,0];
            end

            escolhabgMetodos = app.SelecioneaDerivadaDropDown.Value;

            if(escolhabgMetodos == "Progressiva 2 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxP2] = NDerivacaoP2(f,a,b,h);
                dydxExata = g(x);
                erroP2 = abs(dydxExata -dydxP2);

                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer cada elemento de x
                    dydxExata(i) = g(x(i)); % Calcula o valor de dy/dx para o elemento atual de x e o armazena em dydxExata
                end

                % O código acima calcula o valor de dy/dx para cada elemento em x usando a função g e armazena os valores em dydxExata.
                % O resultado final é um vetor dydxExata que contém os valores calculados de dy/dx correspondentes a cada elemento em x.


                tabela = array2table([x.',y.',dydxExata.', dydxP2.',erroP2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-k*")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-c*")
                plot(app.UIAxes,x,dydxP2,"-r*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Progressiva 2','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Progressiva 2"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhabgMetodos == "Regressiva 2 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxR2] = NDerivacaoR2(f,a,b,h);
                dydxExata = g(x);
                erroR2 = abs(dydxExata -dydxR2);
                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer cada elemento de x
                    dydxExata(i) = g(x(i)); % Calcula o valor de dy/dx para o elemento atual de x e o armazena em dydxExata
                end

                % O código acima calcula o valor de dy/dx para cada elemento em x usando a função g e armazena os valores em dydxExata.
                % O resultado final é um vetor dydxExata que contém os valores calculados de dy/dx correspondentes a cada elemento em x.
                tabela = array2table([x.',y.',dydxExata.', dydxR2.',erroR2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-k*")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-c*")
                plot(app.UIAxes,x,dydxR2,"-r*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Regressiva 2','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Regressiva 2"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhabgMetodos == "Progressiva 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxP3] = NDerivacaoP3(f,a,b,h);
                dydxExata = g(x);
                erroP3 = abs(dydxExata -dydxP3);
                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer cada elemento de x
                    dydxExata(i) = g(x(i)); % Calcula o valor de dy/dx para o elemento atual de x e o armazena em dydxExata
                end

                % O código acima calcula o valor de dy/dx para cada elemento em x usando a função g e armazena os valores em dydxExata.
                % O resultado final é um vetor dydxExata que contém os valores calculados de dy/dx correspondentes a cada elemento em x.
                tabela = array2table([x.',y.',dydxExata.', dydxP3.',erroP3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-k*")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-c*")
                plot(app.UIAxes,x,dydxP3,"-r*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Progressiva 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Progressiva 3"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhabgMetodos == "Regressiva 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxR3] = NDerivacaoR3(f,a,b,h);
                dydxExata = g(x);
                erroR3 = abs(dydxExata -dydxR3);
                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer cada elemento de x
                    dydxExata(i) = g(x(i)); % Calcula o valor de dy/dx para o elemento atual de x e o armazena em dydxExata
                end

                % O código acima calcula o valor de dy/dx para cada elemento em x usando a função g e armazena os valores em dydxExata.
                % O resultado final é um vetor dydxExata que contém os valores calculados de dy/dx correspondentes a cada elemento em x.
                tabela = array2table([x.',y.',dydxExata.', dydxR3.',erroR3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-k*")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-c*")
                plot(app.UIAxes,x,dydxR3,"-r*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Regressiva 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Regressiva 3"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhabgMetodos == "Centrada 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxC3] = NDerivacaoC3(f,a,b,h);
                dydxExata = g(x);
                erroC3 = abs(dydxExata -dydxC3);
                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer cada elemento de x
                    dydxExata(i) = g(x(i)); % Calcula o valor de dy/dx para o elemento atual de x e o armazena em dydxExata
                end

                % O código acima calcula o valor de dy/dx para cada elemento em x usando a função g e armazena os valores em dydxExata.
                % O resultado final é um vetor dydxExata que contém os valores calculados de dy/dx correspondentes a cada elemento em x.
                tabela = array2table([x.',y.',dydxExata.', dydxC3.',erroC3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-k*")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-c*")
                plot(app.UIAxes,x,dydxC3,"-r*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Centrada 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Centrada 3"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhabgMetodos == "2ª derivada 3 pontos")
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxD2] = NDerivacaoD2(f,a,b,h);
                dydxExata = g(x);
                erroD2 = abs(dydxExata -dydxD2);
                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer cada elemento de x
                    dydxExata(i) = g(x(i)); % Calcula o valor de dy/dx para o elemento atual de x e o armazena em dydxExata
                end

                % O código acima calcula o valor de dy/dx para cada elemento em x usando a função g e armazena os valores em dydxExata.
                % O resultado final é um vetor dydxExata que contém os valores calculados de dy/dx correspondentes a cada elemento em x.
                tabela = array2table([x.',y.',dydxExata.', dydxD2.',erroD2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes,x,y,"-k*")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-c*")
                plot(app.UIAxes,x,dydxD2,"-r*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','2ªDerivada 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","2ª Derivada"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end

            app.UITable.ColumnName = nomeColunas;
            legend(app.UIAxes,legendaGrafico);


            try


            catch ME
                errordlg(ME.message,"ERRO","modal")
            end


        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.MquinaCDIUIFigure)
            end
        end

        % Button pushed function: ExecutarButton_3
        function ExecutarButton_3Pushed(app, event)

            strF=app.eF_2.Value;
            f=@(x,y) eval(vectorize(strF));
            %eixo dos x
            a=app.eA_2.Value;
            b=app.eB_2.Value;
            p2=app.eH_2.Value;
            %eixo dos y
            c=app.eC_2.Value;
            d=app.eD_2.Value;
            p=app.eH_2.Value;
            

            if(d < c)
                errordlg("Introduza d > c", "Erro","modal")
                app.eC_2.BackgroundColor = 'r';
                app.eC_2.FontColor = "black";
                app.eD_2.BackgroundColor = 'r';
                app.eD_2.FontColor = "black";
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza d > c');
                throw(erroFuncao)
            else
                app.eD_2.BackgroundColor = "w";
                app.eD_2.FontColor = [0,0,0];
                app.eC_2.BackgroundColor = "w";
                app.eC_2.FontColor = [0,0,0];
            end

            if(  b < a )
                errordlg("Introduza b > a", "Erro","modal")
                app.eB_2.BackgroundColor = 'r';
                app.eB_2.FontColor = "black";
                app.eA_2.BackgroundColor = 'r';
                app.eA_2.FontColor = "black";
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza b > a');
                throw(erroFuncao)
            else

                app.eB_2.BackgroundColor = "w";
                app.eB_2.FontColor = [0,0,0];
                app.eA_2.BackgroundColor = "w";
                app.eA_2.FontColor = [0,0,0];
            end
            
            if(  (c < a) || (d > b) )
                errordlg(" (d,c) tem de estar dentro do intrevalo (b,a) ", "Erro","modal")
                app.eB_2.BackgroundColor = 'r';
                app.eB_2.FontColor = "black";
                app.eA_2.BackgroundColor = 'r';
                app.eA_2.FontColor = "black";
                app.eC_2.BackgroundColor = 'r';
                app.eC_2.FontColor = "black";
                app.eD_2.BackgroundColor = 'r';
                app.eD_2.FontColor = "black";
                erroFuncao = MException('MATLAB:invalidInput', ' (d,c) tem de estar dentro do intrevalo (b,a) ');
                throw(erroFuncao)
            else
                app.eD_2.BackgroundColor = "w";
                app.eD_2.FontColor = [0,0,0];
                app.eC_2.BackgroundColor = "w";
                app.eC_2.FontColor = [0,0,0];
                app.eB_2.BackgroundColor = "w";
                app.eB_2.FontColor = [0,0,0];
                app.eA_2.BackgroundColor = "w";
                app.eA_2.FontColor = [0,0,0];
            end


            [x,y] = meshgrid(a:p2:b,c:p:d);
            Z= f(x,y);
            surf(app.UIAxes_2,x,y,Z)
            contour(app.UIAxes2_2,x,y,Z,20)


            syms x y
            if isHarmonica(app,f)
                app.harmonica_2.Text = "Verdade, a função é harmónica";
                app.harmonica_2.FontColor = 'g';
                 app.Lamp.Color = 'g';
            else
                app.harmonica_2.Text = "Falso, a função não é harmónica";
                app.harmonica_2.FontColor = 'r';
                app.Lamp.Color = 'r';
            end


            dzdx = diff(f,x);
            dzdx_latex = ['$' '\frac{dz}{dx} = ' latex(dzdx) '$'];
            cla(app.UIAxes3_2)
            text(app.UIAxes3_2,0.1,0.5,dzdx_latex,'units','normalized','Interpreter','latex','FontSize',20)

    f_handle = @(x, y) arrayfun(f, x, y);
   integ2 = integral2(f_handle, a, b, c, d);
    
    % Exibir o resultado da integral dupla
    integral_latex = ['$\int_{' num2str(a) '}^{' num2str(b) '} \int_{' num2str(c) '}^{' num2str(d) '} f(x,y) \, dy \, dx = ' num2str(round(integ2,2)) '$'];
    cla(app.UIAxes3_3);
    text(app.UIAxes3_3, 0.1, 0.5, integral_latex, 'units', 'normalized', 'Interpreter', 'latex', 'FontSize', 20);

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autoress
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %botao reset
            % Mostra uma caixa de diálogo com uma mensagem e botões de opção

            choice = questdlg('Tem certeza que quer dar reset?', ...
                'Confirmação', ...
                'Sim','Não','Não');
            % Verifica qual botão foi pressionado
            switch choice
                case 'Sim'
                    app.SelecioneaDerivadaDropDown.Value = "Progressiva 2 pontos" ; %colocar o dropdown default
                    app.eF.Value = 'sin(x)';
                    app.eA.Value = 0;
                    app.eH.Value = 0.1; %valor numerico
                    app.eB.Value = 7 ;


                    app.SelecioneaDerivadaDropDown.Value = "Progressiva 2 pontos"; %botao euler a ser inicializado
                    ExecutarButton_2Pushed(app, event); %chamar a funcao inicializada

                case 'Não'
                    % Não faz o reset
            end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            %botao reset
            % Mostra uma caixa de diálogo com uma mensagem e botões de opção

            choice = questdlg('Tem certeza que quer dar reset?', ...
                'Confirmação', ...
                'Sim','Não','Não');
            % Verifica qual botão foi pressionado
            switch choice
                case 'Sim'

                    app.eF_2.Value = 'x^2+y^2';
                    app.eA_2.Value = -1;
                    app.eH_2.Value = 0.1;
                    app.eB_2.Value = 1;
                    app.eC_2.Value = -1;
                    app.eH_3.Value= 0.1;
                    app.eD_2.Value = 1;


                    ExecutarButton_3Pushed(app, event); %chamar a funcao inicializada

                case 'Não'
                    % Não faz o reset
            end
        end

        % Button pushed function: ExportarTabelaparaexcelButton
        function ExportarTabelaparaexcelButtonPushed(app, event)
            % Obter os dados da tabela que deseja exportar
            dados = app.UITable.Data;
            colunas = app.UITable.ColumnName;

            % Verificar se há tabelas aninhadas e convertê-las em células
            dados = convertNestedTablesToCells(dados);

            % Criar uma tabela a partir dos dados e nomes das colunas
            tabela = array2table(dados, 'VariableNames', colunas);

            % Especificar o nome do arquivo Excel de destino
            nomeFicheiro = 'dados.xlsx';
            % Se o excel existir é apagado primeiro (dados)
            if isfile("dados.xlsx")
                delete("dados.xlsx");
            end
            % Guardar a tabela no ficheiro Excel
            writetable(tabela, nomeFicheiro);

            % Mostrar uma mensagem de confirmação
            msgbox('Tabela exportada com sucesso para o Excel.', 'Exportação Concluída');

            % Função para converter tabelas alinhadas em células
            function data = convertNestedTablesToCells(data)
                if istable(data)
                    data = table2cell(data);
                elseif iscell(data)
                    for i = 1:numel(data)
                        data{i} = convertNestedTablesToCells(data{i});
                    end
                end
            end
            winopen(nomeFicheiro);
        end

        % Button pushed function: ExportarGrficoparaImagemButton
        function ExportarGrficoparaImagemButtonPushed(app, event)
            % Receber o nome do ficheiro
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);

            % Tira uma print ao grafico
            exportgraphics(app.UIAxes, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Button pushed function: ExportarGrafico1paraimagemButton
        function ExportarGrafico1paraimagemButtonPushed(app, event)
            % Receber o nome do ficheiro
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);

            % Tira uma print ao grafico
            exportgraphics(app.UIAxes_2, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Button pushed function: ExportarGrafico2paraimagemButton
        function ExportarGrafico2paraimagemButtonPushed(app, event)
            % Receber o nome do ficheiro
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);

            % Tira uma print ao grafico
            exportgraphics(app.UIAxes2_2, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Menu selected function: AbrirRelatorioMenu
        function AbrirRelatorioMenuSelected(app, event)
            open('Pedro_Sherring - 2020126540_Tiago_Oliveira-2022137225.pdf');
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            
            strF = app.eF_3.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA_3.Value; n = app.eN.Value; b = app.eB_3.Value;
            cla(app.UIRegras);
            cla(app.UIIntegral);
            
             if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                app.eB_3.BackgroundColor = 'r';
                app.eB_3.FontColor = "black";
                app.eA_3.BackgroundColor = 'r';
                app.eA_3.FontColor = "black";
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza b > a');
                throw(erroFuncao)
             else
                app.eB_3.BackgroundColor = "w";
                app.eB_3.FontColor = [0,0,0];
                app.eA_3.BackgroundColor = "w";
                app.eA_3.FontColor = [0,0,0];
             end

             if( n < 1)
                errordlg("Introduza n > 0", "Erro","modal")
                app.eN.BackgroundColor = 'r';
                app.eN.FontColor = "black";
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza n > 0');
                throw(erroFuncao)
             else
                app.eN.BackgroundColor = "w";
                app.eN.FontColor = [0,0,0];
            end

            int= integral(f,a,b);
            % Calcular a integral usando a Regra dos Trapézios
            T = Trapezios(f, a, b, n);

            % Calcular a integral usando a Regra de Simpson
            S = Simpson(f, a, b, n);

            escolhabgMetodos = app.bgMetodos.SelectedObject;
            rbMetodoSelecionado = find([app.RegraTrapziosButton,...
                app.RegraSimpsonButton,...
                app.TodosButton]==escolhabgMetodos);


            formaLatex = sprintf('\\int_{%i}^{%i} %s =%s',a,b, strF, num2str(round(int,2)) );
         text(app.UIIntegral, 0.5, 0.5, formaLatex,'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 15, 'Units', 'normalized');


            switch rbMetodoSelecionado
                case 1

                    result_str = sprintf('Regra dos Trapézios: %.2f\n', round(T,2) );

                    text(app.UIRegras, 0.5, 0.5, result_str,'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 15, 'Units', 'normalized');

                case 2
                    result_str = sprintf('Regra de Simpson: %.2f\n', round(S,2) );

                    text(app.UIRegras, 0.5, 0.5, result_str,'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 15, 'Units', 'normalized');

                case 3
                    result_str = sprintf('Regra dos Trapézios: %.2f\nRegra de Simpson: %.2f', round(T,2), round(S,2));

                    text(app.UIRegras, 0.5, 0.5, result_str,'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 15, 'Units', 'normalized');
            end

        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
                        %botao reset
            % Mostra uma caixa de diálogo com uma mensagem e botões de opção

            choice = questdlg('Tem certeza que quer dar reset?', ...
                'Confirmação', ...
                'Sim','Não','Não');
            % Verifica qual botão foi pressionado
            switch choice
                case 'Sim'

                    app.eF_3.Value = '(x+3)*exp(x/2)';
                    app.eA_3.Value = 0;
                    app.eN.Value = 10;
                    app.eB_3.Value = 10 ;


                    cla(app.UIRegras);
                    
                    
                    cla(app.UIIntegral);


                case 'Não'
                    % Não faz o reset
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MquinaCDIUIFigure = uifigure('Visible', 'off');
            app.MquinaCDIUIFigure.Name = 'MáquinaCDI';
            app.MquinaCDIUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.Menu = uimenu(app.MquinaCDIUIFigure);
            app.Menu.Text = '  Menu  ';
            app.AbrirRelatorioMenu = uimenu(app.Menu);
            app.AbrirRelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirRelatorioMenuSelected, true);
            app.AbrirRelatorioMenu.Text = 'Abrir Relatorio';
            app.AutoresMenu = uimenu(app.Menu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.TabGroup = uitabgroup(app.MquinaCDIUIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'y = f(x)  ';
            app.UIAxes = uiaxes(app.tgFrVr);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.tgFrVr);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.DadosdafunoPanel = uipanel(app.tgFrVr);
            app.DadosdafunoPanel.Title = 'Dados da função';
            app.fxLabel = uilabel(app.DadosdafunoPanel);
            app.fxLabel.Text = 'f(x)=';
            app.eF = uieditfield(app.DadosdafunoPanel, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.DadosdafunoPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.hLabel = uilabel(app.DadosdafunoPanel);
            app.hLabel.Text = 'h';
            app.eH = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eH.Limits = [0 Inf];
            app.eH.Value = 0.1;
            app.bLabel = uilabel(app.DadosdafunoPanel);
            app.bLabel.Text = 'b';
            app.eB = uieditfield(app.DadosdafunoPanel, 'numeric');
            app.eB.Value = 7;
            app.ExecutarButton_2 = uibutton(app.tgFrVr, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = {'Executar'; ''};
            app.UITable = uitable(app.tgFrVr);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SelecioneaDerivadaDropDownLabel = uilabel(app.tgFrVr);
            app.SelecioneaDerivadaDropDownLabel.Text = 'Selecione a Derivada';
            app.SelecioneaDerivadaDropDown = uidropdown(app.tgFrVr);
            app.SelecioneaDerivadaDropDown.Items = {'Progressiva 2 pontos', 'Regressiva 2 pontos', 'Progressiva 3 pontos', 'Regressiva 3 pontos', 'Centrada 3 pontos', '2ª derivada 3 pontos'};
            app.SelecioneaDerivadaDropDown.Value = 'Progressiva 2 pontos';
            app.ResetButton = uibutton(app.tgFrVr, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportarTabelaparaexcelButton = uibutton(app.tgFrVr, 'push');
            app.ExportarTabelaparaexcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarTabelaparaexcelButtonPushed, true);
            app.ExportarTabelaparaexcelButton.Text = 'Exportar Tabela para excel';
            app.ExportarGrficoparaImagemButton = uibutton(app.tgFrVr, 'push');
            app.ExportarGrficoparaImagemButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarGrficoparaImagemButtonPushed, true);
            app.ExportarGrficoparaImagemButton.Text = 'Exportar Gráfico para Imagem';
            app.DerivadaLabel_2 = uilabel(app.tgFrVr);
            app.DerivadaLabel_2.Text = 'Derivada';
            app.tgFrVr_2 = uitab(app.TabGroup);
            app.tgFrVr_2.Title = '  z = f(x,y)  ';
            app.UIAxes_2 = uiaxes(app.tgFrVr_2);
            title(app.UIAxes_2, 'Grafico 1')
            xlabel(app.UIAxes_2, 'x')
            ylabel(app.UIAxes_2, 'y')
            zlabel(app.UIAxes_2, 'Z')
            colormap(app.UIAxes_2, 'spring')
            app.UIAxes2_2 = uiaxes(app.tgFrVr_2);
            title(app.UIAxes2_2, 'Grafico 2')
            xlabel(app.UIAxes2_2, 'x')
            ylabel(app.UIAxes2_2, 'y')
            zlabel(app.UIAxes2_2, 'Z')
            colormap(app.UIAxes2_2, 'spring')
            app.UIAxes3_2 = uiaxes(app.tgFrVr_2);
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.UIAxes3_2.ZTick = [];
            app.UIAxes3_3 = uiaxes(app.tgFrVr_2);
            app.UIAxes3_3.XTick = [];
            app.UIAxes3_3.YTick = [];
            app.UIAxes3_3.ZTick = [];
            app.DadosdafunoPanel_2 = uipanel(app.tgFrVr_2);
            app.DadosdafunoPanel_2.Title = 'Dados da função';
            app.xyLabel = uilabel(app.DadosdafunoPanel_2);
            app.xyLabel.Text = 'f(x,y) =';
            app.eF_2 = uieditfield(app.DadosdafunoPanel_2, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.aLabel_2 = uilabel(app.DadosdafunoPanel_2);
            app.aLabel_2.Text = 'a';
            app.eA_2 = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eA_2.Value = -1;
            app.cLabel = uilabel(app.DadosdafunoPanel_2);
            app.cLabel.Text = 'c';
            app.eC_2 = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eC_2.Value = -1;
            app.dLabel = uilabel(app.DadosdafunoPanel_2);
            app.dLabel.Text = 'd';
            app.eD_2 = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eD_2.Value = 1;
            app.bLabel_2 = uilabel(app.DadosdafunoPanel_2);
            app.bLabel_2.Text = 'b';
            app.eB_2 = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eB_2.Value = 1;
            app.h2Label = uilabel(app.DadosdafunoPanel_2);
            app.h2Label.Text = 'h2';
            app.eH_3 = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eH_3.Limits = [0 Inf];
            app.eH_3.Value = 0.1;
            app.h1Label = uilabel(app.DadosdafunoPanel_2);
            app.h1Label.Text = 'h1';
            app.eH_2 = uieditfield(app.DadosdafunoPanel_2, 'numeric');
            app.eH_2.Limits = [0 Inf];
            app.eH_2.Value = 0.1;
            app.ExecutarButton_3 = uibutton(app.tgFrVr_2, 'push');
            app.ExecutarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_3Pushed, true);
            app.ExecutarButton_3.Text = 'Executar';
            app.ResetButton_2 = uibutton(app.tgFrVr_2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ExportarGrafico1paraimagemButton = uibutton(app.tgFrVr_2, 'push');
            app.ExportarGrafico1paraimagemButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarGrafico1paraimagemButtonPushed, true);
            app.ExportarGrafico1paraimagemButton.Text = 'Exportar Grafico 1 para imagem';
            app.ExportarGrafico2paraimagemButton = uibutton(app.tgFrVr_2, 'push');
            app.ExportarGrafico2paraimagemButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarGrafico2paraimagemButtonPushed, true);
            app.ExportarGrafico2paraimagemButton.Text = 'Exportar Grafico 2 para imagem';
            app.DerivadaLabel_3 = uilabel(app.tgFrVr_2);
            app.DerivadaLabel_3.Text = 'Derivada';
            app.IntegralduploLabel = uilabel(app.tgFrVr_2);
            app.IntegralduploLabel.Text = 'Integral duplo';
            app.Panel_2 = uipanel(app.tgFrVr_2);
            app.harmonicaLabel = uilabel(app.Panel_2);
            app.harmonicaLabel.Text = 'É harmonica ?';
            app.harmonica_2 = uilabel(app.Panel_2);
            app.harmonica_2.Text = 'Verdade, a função é harmonica';
            app.Lamp = uilamp(app.Panel_2);
            app.IntegraonmericaTab = uitab(app.TabGroup);
            app.IntegraonmericaTab.Title = 'Integração númerica';
            app.bgMetodos = uibuttongroup(app.IntegraonmericaTab);
            app.bgMetodos.Title = 'Métodos de Integracao Numérica';
            app.UIRegras = uiaxes(app.bgMetodos);
            app.UIRegras.XTick = [];
            app.UIRegras.YTick = [];
            app.UIRegras.ZTick = [];
            app.RegraTrapziosButton = uiradiobutton(app.bgMetodos);
            app.RegraTrapziosButton.Text = 'Regra Trapézios';
            app.RegraTrapziosButton.Value = true;
            app.RegraSimpsonButton = uiradiobutton(app.bgMetodos);
            app.RegraSimpsonButton.Text = 'Regra Simpson';
            app.TodosButton = uiradiobutton(app.bgMetodos);
            app.TodosButton.Text = 'Todos';
            app.ExecutarButton = uibutton(app.bgMetodos, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton_3 = uibutton(app.bgMetodos, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.ResultadodosMtodosdeIntegraaoLabel = uilabel(app.bgMetodos);
            app.ResultadodosMtodosdeIntegraaoLabel.Text = 'Resultado dos Métodos de Integraçao';
            app.Panel = uipanel(app.IntegraonmericaTab);
            app.UIIntegral = uiaxes(app.Panel);
            app.UIIntegral.XTick = [];
            app.UIIntegral.YTick = [];
            app.UIIntegral.ZTick = [];
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB_3 = uieditfield(app.Panel, 'numeric');
            app.eB_3.Value = 10;
            app.aEditField_2Label = uilabel(app.Panel);
            app.aEditField_2Label.Text = 'a';
            app.eA_3 = uieditfield(app.Panel, 'numeric');
            app.ExpresoLabel = uilabel(app.Panel);
            app.ExpresoLabel.Text = 'Expresão';
            app.eF_3 = uieditfield(app.Panel, 'text');
            app.eF_3.Value = '(x+3)*exp(x/2)';
            app.nLabel = uilabel(app.Panel);
            app.nLabel.Text = 'n';
            app.eN = uieditfield(app.Panel, 'numeric');
            app.eN.Value = 10;
            app.IntegralLabel = uilabel(app.Panel);
            app.IntegralLabel.Text = 'Integral';
            % Show the figure after all components are created
            app.MquinaCDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MquinaCDIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MquinaCDIUIFigure)
        end
    end
end